classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        redcupT     matlab.ui.control.Button
        utensilsT   matlab.ui.control.Button
        hotdogC     matlab.ui.control.Button
        flowersC    matlab.ui.control.Button
        teabagC     matlab.ui.control.Button
        metalcanR   matlab.ui.control.Button
        cardboardR  matlab.ui.control.Button
        chipsT      matlab.ui.control.Button
        background  matlab.ui.control.Image
    end

    
    properties (Access = public)
        
        button_name ='redcupT'
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.button_name = 'redcupT'
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
              key = event.Key
%             if  app.redcupT.Tag== '1';
%                 app.last_button = app.redcupT;
%             end
%             if  app.utensilsT.Value == 1;
%                 app.last_button = app.utensilsT;
%             end
%             if  app.hotdogC.Value == 1;
%                 app.last_button = app.hotdogC;
%             end
%             if  app.flowersC.Value == 1;
%                 app.last_button = app.flowersC;
%             end
%             if  app.teabagC.Value == 1;
%                 app.last_button = app.teabagC;
%             end
%             if  app.metalcanR.Value == 1;
%                 app.last_button = app.metalcanR;
%             end
%             if  app.cardboardR.Value == 1;
%                 app.last_button = app.cardboardR;
%             end
%             if  app.chipsT.Value == 1;
%                 app.last_button = app.chipsT;
%             end    
            last_button = app.(app.button_name)
            %if app.last_button ~= 0 
            
                if strcmp(key,'leftarrow')
                    last_button.Position(1) = last_button.Position(1) -10
                end
                if strcmp(key,'rightarrow')
                    last_button.Position(1) = last_button.Position(1) +10
                end
                if strcmp(key,'uparrow')
                    last_button.Position(2) = last_button.Position(2) +10
                end
                if strcmp(key,'downarrow')
                    last_button.Position(2) = last_button.Position(2) -10
                end
        end

        % Button pushed function: utensilsT
        function utensilsTButtonPushed(app, event)
          app.button_name= "utensilsT";
        end

        % Button pushed function: redcupT
        function redcupTButtonPushed(app, event)
          app.button_name= "redcupT" ;
        end

        % Button pushed function: hotdogC
        function hotdogCButtonPushed(app, event)
           app.button_name= "hotdogC";
        end

        % Button pushed function: flowersC
        function flowersCButtonPushed(app, event)
          app.button_name= "flowersC";
        end

        % Button pushed function: teabagC
        function teabagCButtonPushed(app, event)
          app.button_name= "teabagC";
        end

        % Button pushed function: metalcanR
        function metalcanRButtonPushed(app, event)
          app.button_name= "metalcanR";
        end

        % Button pushed function: cardboardR
        function cardboardRButtonPushed(app, event)
          app.button_name= "cardboardR";
        end

        % Button pushed function: chipsT
        function chipsTButtonPushed(app, event)
            app.button_name= 'chipsT';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = fullfile(pathToMLAPP, 'LabelledTrashcan.png');
            app.chipsT = uibutton(app.UIFigure, 'push');
            app.chipsT.ButtonPushedFcn = createCallbackFcn(app, @chipsTButtonPushed, true);
            app.chipsT.Icon = fullfile(pathToMLAPP, 'lays chips.png');
            app.chipsT.Text = '';
            app.cardboardR = uibutton(app.UIFigure, 'push');
            app.cardboardR.ButtonPushedFcn = createCallbackFcn(app, @cardboardRButtonPushed, true);
            app.cardboardR.Icon = fullfile(pathToMLAPP, 'cardboard.png');
            app.cardboardR.Text = '';
            app.metalcanR = uibutton(app.UIFigure, 'push');
            app.metalcanR.ButtonPushedFcn = createCallbackFcn(app, @metalcanRButtonPushed, true);
            app.metalcanR.Icon = fullfile(pathToMLAPP, 'metal can.png');
            app.metalcanR.Text = '';
            app.teabagC = uibutton(app.UIFigure, 'push');
            app.teabagC.ButtonPushedFcn = createCallbackFcn(app, @teabagCButtonPushed, true);
            app.teabagC.Icon = fullfile(pathToMLAPP, 'tea bag.png');
            app.teabagC.Text = '';
            app.flowersC = uibutton(app.UIFigure, 'push');
            app.flowersC.ButtonPushedFcn = createCallbackFcn(app, @flowersCButtonPushed, true);
            app.flowersC.Icon = fullfile(pathToMLAPP, 'dead flowers.png');
            app.flowersC.Text = '';
            app.hotdogC = uibutton(app.UIFigure, 'push');
            app.hotdogC.ButtonPushedFcn = createCallbackFcn(app, @hotdogCButtonPushed, true);
            app.hotdogC.Icon = fullfile(pathToMLAPP, 'hotdog.png');
            app.hotdogC.Text = '';
            app.utensilsT = uibutton(app.UIFigure, 'push');
            app.utensilsT.ButtonPushedFcn = createCallbackFcn(app, @utensilsTButtonPushed, true);
            app.utensilsT.Icon = fullfile(pathToMLAPP, 'Utensils.png');
            app.utensilsT.Text = '';
            app.redcupT = uibutton(app.UIFigure, 'push');
            app.redcupT.ButtonPushedFcn = createCallbackFcn(app, @redcupTButtonPushed, true);
            app.redcupT.Icon = fullfile(pathToMLAPP, 'plastic_cup.png');
            app.redcupT.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end